classdef a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        EstopSwitch            matlab.ui.control.Switch
        EstopSwitchLabel       matlab.ui.control.Label
        Q1EditField_6          matlab.ui.control.NumericEditField
        Q1EditField_6Label     matlab.ui.control.Label
        Q1EditField_3          matlab.ui.control.NumericEditField
        Q1EditField_3Label     matlab.ui.control.Label
        ZpositionSpinner       matlab.ui.control.Spinner
        ZpositionSpinnerLabel  matlab.ui.control.Label
        XpositionSpinner       matlab.ui.control.Spinner
        XpositionSpinnerLabel  matlab.ui.control.Label
        LoadthetestingxyzrobotpositionButton  matlab.ui.control.Button
        Joint1Spinner          matlab.ui.control.Spinner
        Joint1SpinnerLabel     matlab.ui.control.Label
        YpositionSpinner       matlab.ui.control.Spinner
        YpositionSpinnerLabel  matlab.ui.control.Label
        Joint7Spinner          matlab.ui.control.Spinner
        Joint7SpinnerLabel     matlab.ui.control.Label
        Joint6Spinner          matlab.ui.control.Spinner
        Joint6SpinnerLabel     matlab.ui.control.Label
        Joint4Spinner          matlab.ui.control.Spinner
        Joint4SpinnerLabel     matlab.ui.control.Label
        Joint5Spinner          matlab.ui.control.Spinner
        Joint5SpinnerLabel     matlab.ui.control.Label
        Joint3Spinner          matlab.ui.control.Spinner
        Joint3SpinnerLabel     matlab.ui.control.Label
        Joint2Spinner          matlab.ui.control.Spinner
        Joint2SpinnerLabel     matlab.ui.control.Label
        loadtherobotButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Library_access 
        Library_robot 
        qz = zeros(1,6)
        q1 = 0
        q2 =0
 
        Joint_1= 10
%         Joint_1_1=0
%         Joint_1_2=0

        Joint_2= 0
%         Joint_2_1=0
%         Joint_2_2=0

        Joint_3= 0
%         Joint_3_1=0
%         Joint_3_2=0

        Joint_4= 0
%         Joint_4_1=0
%         Joint_4_2=0

        Joint_5= 0
%         Joint_5_1=0
%         Joint_5_2=0

        Joint_6= 0
%         Joint_6_1=0
%         Joint_6_2=0

        Joint_7=0
%         Joint_7_1=0
%         Joint_7_2=0

        x=0      %Testing x position that won't cause much singularities 
        y=-0.21  %Testing y position that won't cause much singularities 
        z=0.45   %Testing z position that won't cause much singularities 
%         count_1_changed=0
%         count_1_changing=0
        Qmatrix
        qn =[0 0 0 0 0 0 0]
        previous_position %This would update the current joint value of the robot
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadtherobotButton
        function loadtherobotButtonPushed(app, event)

            app.Library_robot = HansCute;  % load the hanscute robot
            plot(app.Library_robot.model,app.qn); %plot the robot
            drawnow();

        end

        % Value changed function: Joint1Spinner
        function Joint1SpinnerValueChanged(app, event)

    % IGNORE THIS            
%            app.count_1_changing = app.count_1_changed+1;
%            app.previous_position  = app.Library_robot.model.getpos;
%           
%             if(app.count_1_changing<=2)
%             
%             
% %             app.Joint_1_2      = Previous_position(1,1);
%             app.Joint_1_1  = event.Value;
%           
% %             app.Joint_1_2 =deg2rad(app.Joint_1_2);
%             app.Joint_1_1 =deg2rad(app.Joint_1_1);
%             
% % 
% %             app.Joint_1_1  = event.Value;
% %             app.Joint_1_2  = event.PreviousValue;
% %             
% %             app.Joint_2_1  = event.Value;
% %             app.Joint_2_2  = event.PreviousValue;
% %         
% %             app.Joint_1_1 =deg2rad(app.Joint_1_1);
% %             app.Joint_1_2 =deg2rad(app.Joint_1_2);
% % 
% %             app.Joint_2_1 =deg2rad(app.Joint_2_1);
% %             app.Joint_2_2 =deg2rad(app.Joint_2_2);
% % 
%            app.q2 = app.previous_position;
% %            app.q2 = [app.Joint_1_2 app.Joint_2_2 0 0 0 0 0];
%            app.q1 = [app.Joint_1_1 app.Joint_2_1 app.Joint_3_1 0 0 0 0];
%             
% %           
% %          
%             app.Qmatrix = jtraj(app.q2,app.q1,20);
%             animate(app.Library_robot.model,app.Qmatrix);
%             drawnow();
%             app.count_1_changed  =0;
%             app.count_1_changing =0;
%             end 
% %               app.previous_position  = app.Library_robot.model.getpos;
% %               animate(app.Library_robot.model,app.previous_position);
%             
% %                 
        end

        % Value changing function: Joint1Spinner
        function Joint1SpinnerValueChanging(app, event)

             app.previous_position  = app.Library_robot.model.getpos; %get the current position qi 
             app.Joint_1= event.Value;  %q1 equal to the current number on the spinner
            app.Joint_1 = deg2rad(app.Joint_1); 
             app.qn = app.previous_position;
             app.qn (1,1) = app.Joint_1;
             animate(app.Library_robot.model,app.qn);
             drawnow();
             app.previous_position  = app.Library_robot.model.getpos;
             current_position = app.Library_robot.model.fkine(app.previous_position);
             app.x= current_position(1,4);
             app.y= current_position(2,4);
             app.z= current_position(3,4);

            app.XpositionSpinner.Value = app.x;
            app.YpositionSpinner.Value = app.y;
            app.ZpositionSpinner.Value = app.z;
          
        end

        % Value changed function: Joint2Spinner
        function Joint2SpinnerValueChanged(app, event)

            % IGNORE THIS

%             app.count_1_changing = app.count_1_changed+1;
%             app.previous_position  = app.Library_robot.model.getpos;
%             
%             if(app.count_1_changing<=2)
%            
%             
% %                  app.Joint_2_2      =  Previous_position;
% %             app.Joint_2_2  = event.PreviousValue;
%             app.Joint_2_1  = event.Value;
%             
%         
% %             app.Joint_1_1 =deg2rad(app.Joint_1_1);
% %             app.Joint_1_2 =deg2rad(app.Joint_1_2);
% %             app.Joint_2_2 =deg2rad(app.Joint_2_2);
%             app.Joint_2_1 =deg2rad(app.Joint_2_1);
% 
% %             app.Joint_2_2 =deg2rad(app.Joint_2_2);
% %             app.q2 = [app.Joint_1_2 app.Joint_2_2 0 0 0 0 0];
%             app.q2 = app.previous_position;
%             app.q1 = [app.Joint_1_1 app.Joint_2_1 app.Joint_3_1 0 0 0 0];
%            
%           
%          
%             app.Qmatrix = jtraj(app.q2,app.q1,20);
%             animate(app.Library_robot.model,app.Qmatrix);
%             drawnow();
%             app.count_1_changed  =0;
%             app.count_1_changing =0;
%             app.previous_position  = app.Library_robot.model.getpos;
%             end 
%             app.previous_position  = app.Library_robot.model.getpos;
% 
%             if(app.count_1_changing>2)
%                 app.count_1_changed  =0;
%                 app.count_1_changing =0;
%             end
% %               app.previous_position  = app.Library_robot.model.getpos;
% %               animate(app.Library_robot.model,app.previous_position);
        end

        % Value changing function: Joint2Spinner
        function Joint2SpinnerValueChanging(app, event)
            

             % IGNORE THIS

%             app.Joint_1 = app.Joint1Spinner.Value; 
%             app.Joint_2 = app.Joint2Spinner.Value;
%             app.Joint_3 = app.Joint3Spinner.Value;
%             app.Joint_4 = app.Joint4Spinner.Value;
%             app.Joint_5 = app.Joint5Spinner.Value;
%             app.Joint_6 = app.Joint6Spinner.Value;
% %                  ax = gca;
% %             mdl_puma560
% %             app.modelPuma = p560;
%             app.Joint_6 = deg2rad(app.Joint_6);
%             app.Joint_5 = deg2rad(app.Joint_5);
%             app.Joint_4 = deg2rad(app.Joint_4);
%             app.Joint_3 = deg2rad(app.Joint_3);
%             app.Joint_2 = deg2rad(app.Joint_2);
%             app.Joint_1 = deg2rad(app.Joint_1);
%             
%             app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 0 ];
% %             
% %             app.modelPuma.animate(qn)
%            animate(app.Library_robot.model, app.qn)
%            drawnow();
             
             app.count_1_changing= app.count_1_changing+1;
             app.previous_position  = app.Library_robot.model.getpos;
             
%              if(app.count_1_changing>1)
%                event.Value = app.previous_position(1,2)
                app.Joint_2 = event.Value;
              app.Joint_2 = deg2rad(app.Joint_2);
%               app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 app.Joint_7];
             app.qn = app.previous_position;
             app.qn (1,2) = app.Joint_2;               

              animate(app.Library_robot.model,app.qn);  
              drawnow();
              
             app.previous_position  = app.Library_robot.model.getpos;
             current_position = app.Library_robot.model.fkine(app.previous_position);
             app.x= current_position(1,4);
             app.y= current_position(2,4);
             app.z= current_position(3,4);

            app.XpositionSpinner.Value = app.x;
            app.YpositionSpinner.Value = app.y;
            app.ZpositionSpinner.Value = app.z;
            
        end

        % Value changed function: Joint3Spinner
        function Joint3SpinnerValueChanged(app, event)

             % IGNORE THIS
            
% %             app.Joint_1 = app.Joint1Spinner.Value;
% %             app.Joint_2 = app.Joint2Spinner.Value;
% %             app.Joint_3 = app.Joint3Spinner.Value;
% %             app.Joint_4 = app.Joint4Spinner.Value;
% %             app.Joint_5 = app.Joint5Spinner.Value;
% %             app.Joint_6 = app.Joint6Spinner.Value;
% % %                  ax = gca;
% % %             mdl_puma560
% % %             app.modelPuma = p560;
% %             app.Joint_6 = deg2rad(app.Joint_6);
% %             app.Joint_5 = deg2rad(app.Joint_5);
% %             app.Joint_4 = deg2rad(app.Joint_4);
% %             app.Joint_3 = deg2rad(app.Joint_3);
% %             app.Joint_2 = deg2rad(app.Joint_2);
% %             app.Joint_1 = deg2rad(app.Joint_1);
% %             
% %             app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 0];
% % %             
% % %             app.modelPuma.animate(qn)
% %            animate(app.Library_robot.model, app.qn)
% %            drawnow();
% 
%          app.count_1_changed = app.count_1_changed+1;
%          app.previous_position  = app.Library_robot.model.getpos;
%          
%             if(app.count_1_changing<=1)
%            
%             Previous_position  = app.Library_robot.model.getpos;
% %                  app.Joint_2_2      =  Previous_position
% %             app.Joint_2_2  = event.PreviousValue;
%             app.Joint_3_1  = event.Value;
%             
%         
% %             app.Joint_1_1 =deg2rad(app.Joint_1_1);
% %             app.Joint_1_2 =deg2rad(app.Joint_1_2);
% %             app.Joint_2_2 =deg2rad(app.Joint_2_2);
%             app.Joint_3_1 =deg2rad(app.Joint_3_1);
% 
% %             app.Joint_2_2 =deg2rad(app.Joint_2_2);
% %             app.q2 = [app.Joint_1_2 app.Joint_2_2 0 0 0 0 0];
%             app.q2 = Previous_position;
%             app.q1 = [app.Joint_1_1 app.Joint_2_1 app.Joint_3_1 0 0 0 0];
%            
%           
%          
%             app.Qmatrix = jtraj(app.q2,app.q1,20);
%             animate(app.Library_robot.model,app.Qmatrix);
%             drawnow();
%             app.count_1_changed  =0;
%             app.count_1_changing =0;
%             
%             end 
%             app.previous_position  = app.Library_robot.model.getpos;
%             if(app.count_1_changing>2)
%                 app.count_1_changed  =0;
%                 app.count_1_changing =0;
%             end
        end

        % Value changing function: Joint3Spinner
        function Joint3SpinnerValueChanging(app, event)
            app.previous_position  = app.Library_robot.model.getpos;
            
            
%             if(app.count_1_changing>1)
%              event.Value = app.previous_position(1,3)
                 app.Joint_3 = event.Value;
            
              app.count_1_changing= app.count_1_changing+1;
              app.Joint_3 = deg2rad(app.Joint_3);
%               app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 app.Joint_7];
             app.qn = app.previous_position;
             app.qn (1,3) = app.Joint_3;
              
              animate(app.Library_robot.model,app.qn);
              drawnow();
                
             app.previous_position  = app.Library_robot.model.getpos;
             current_position = app.Library_robot.model.fkine(app.previous_position);
             app.x= current_position(1,4);
             app.y= current_position(2,4);
             app.z= current_position(3,4);

            app.XpositionSpinner.Value = app.x;
            app.YpositionSpinner.Value = app.y;
            app.ZpositionSpinner.Value = app.z;


        end

        % Value changed function: Joint4Spinner
        function Joint4SpinnerValueChanged(app, event)

             % IGNORE THIS
%             app.Joint_6 = app.Joint6Spinner.Value;
%             app.Joint_5 = app.Joint5Spinner.Value;
%             app.Joint_4 = app.Joint4Spinner.Value;
%             app.Joint_3 = app.Joint3Spinner.Value;
%             app.Joint_2 = app.Joint2Spinner.Value;
%             app.Joint_1 = app.Joint1Spinner.Value; 
%            
% %                  ax = gca;
% %             mdl_puma560
% %             app.modelPuma = p560;
%             app.Joint_6 = deg2rad(app.Joint_6); 
%             app.Joint_5 = deg2rad(app.Joint_5);
%             app.Joint_4 = deg2rad(app.Joint_4);
%             app.Joint_3 = deg2rad(app.Joint_3);
%             app.Joint_2 = deg2rad(app.Joint_2);
%             app.Joint_1 = deg2rad(app.Joint_1);
%             
%             app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 0];
% %             
% %             app.modelPuma.animate(qn)
%            animate(app.Library_robot.model, app.qn)
%            drawnow();
            
        end

        % Value changing function: Joint4Spinner
        function Joint4SpinnerValueChanging(app, event)
            app.previous_position  = app.Library_robot.model.getpos;
             
%              if(app.count_1_changing>1)
%                event.Value = app.previous_position(1,4) 
            app.Joint_4 = event.Value;
              app.count_1_changing= app.count_1_changing+1;
              app.Joint_4 = deg2rad(app.Joint_4);
%               app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 app.Joint_7];
             app.qn = app.previous_position;
             app.qn (1,4) = app.Joint_4;
             
              animate(app.Library_robot.model,app.qn);
              drawnow();

             app.previous_position  = app.Library_robot.model.getpos;
             current_position = app.Library_robot.model.fkine(app.previous_position);
             app.x= current_position(1,4);
             app.y= current_position(2,4);
             app.z= current_position(3,4);

            app.XpositionSpinner.Value = app.x;
            app.YpositionSpinner.Value = app.y;
            app.ZpositionSpinner.Value = app.z;
            
        end

        % Value changed function: Joint5Spinner
        function Joint5SpinnerValueChanged(app, event)

             % IGNORE THIS
%             app.Joint_6 = app.Joint6Spinner.Value;
%             app.Joint_5 = app.Joint5Spinner.Value;
%             app.Joint_4 = app.Joint4Spinner.Value;
%             app.Joint_3 = app.Joint3Spinner.Value;
%             app.Joint_2 = app.Joint2Spinner.Value;
%             app.Joint_1 = app.Joint1Spinner.Value; 
%            
% %                  ax = gca;
% %             mdl_puma560
% %             app.modelPuma = p560;
%             app.Joint_6 = deg2rad(app.Joint_6);
%             app.Joint_5 = deg2rad(app.Joint_5);
%             app.Joint_4 = deg2rad(app.Joint_4);
%             app.Joint_3 = deg2rad(app.Joint_3);
%             app.Joint_2 = deg2rad(app.Joint_2);
%             app.Joint_1 = deg2rad(app.Joint_1);
%             
%             app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 0];
% %             
% %             app.modelPuma.animate(qn)
%            animate(app.Library_robot.model, app.qn)
%            drawnow();
            
        end

        % Value changing function: Joint5Spinner
        function Joint5SpinnerValueChanging(app, event)
            app.previous_position  = app.Library_robot.model.getpos;
             
%              if(app.count_1_changing>1)
%                event.Value = app.previous_position(1,5)  
            app.Joint_5 = event.Value;
              app.count_1_changing= app.count_1_changing+1;
              app.Joint_5 = deg2rad(app.Joint_5);
%               app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 app.Joint_7];
             app.qn = app.previous_position;
             app.qn (1,5) = app.Joint_5;
             
              animate(app.Library_robot.model,app.qn);
              drawnow();

             app.previous_position  = app.Library_robot.model.getpos;
             current_position = app.Library_robot.model.fkine(app.previous_position);
             app.x= current_position(1,4);
             app.y= current_position(2,4);
             app.z= current_position(3,4);

            app.XpositionSpinner.Value = app.x;
            app.YpositionSpinner.Value = app.y;
            app.ZpositionSpinner.Value = app.z;
            
        end

        % Value changed function: Joint6Spinner
        function Joint6SpinnerValueChanged(app, event)

             % IGNORE THIS
            
            %             app.Joint_6 = app.Joint6Spinner.Value;
%             app.Joint_5 = app.Joint5Spinner.Value;
%             app.Joint_4 = app.Joint4Spinner.Value;
%             app.Joint_3 = app.Joint3Spinner.Value;
%             app.Joint_2 = app.Joint2Spinner.Value;
%             app.Joint_1 = app.Joint1Spinner.Value; 
%            
% %                  ax = gca;
% %             mdl_puma560
% %             app.modelPuma = p560;
%             app.Joint_6 = deg2rad(app.Joint_6);
%             app.Joint_5 = deg2rad(app.Joint_5);
%             app.Joint_4 = deg2rad(app.Joint_4);
%             app.Joint_3 = deg2rad(app.Joint_3);
%             app.Joint_2 = deg2rad(app.Joint_2);
%             app.Joint_1 = deg2rad(app.Joint_1);
%             
%             app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 0];
% %             
% %             app.modelPuma.animate(qn)
%            animate(app.Library_robot.model, app.qn)
%            drawnow();
            
        end

        % Value changing function: Joint6Spinner
        function Joint6SpinnerValueChanging(app, event)
            app.previous_position  = app.Library_robot.model.getpos;
             
%              if(app.count_1_changing>1)
%                event.Value = app.previous_position(1,6) 
            app.Joint_6 = event.Value;
              app.count_1_changing= app.count_1_changing+1;
              app.Joint_6 = deg2rad(app.Joint_6);
%               app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 app.Joint_7];
             app.qn = app.previous_position;
             app.qn (1,6) = app.Joint_6;
             
              animate(app.Library_robot.model,app.qn);
              drawnow();

             app.previous_position  = app.Library_robot.model.getpos;
             current_position = app.Library_robot.model.fkine(app.previous_position);
             app.x= current_position(1,4);
             app.y= current_position(2,4);
             app.z= current_position(3,4);

            app.XpositionSpinner.Value = app.x;
            app.YpositionSpinner.Value = app.y;
            app.ZpositionSpinner.Value = app.z;
            
        end

        % Value changed function: Joint7Spinner
        function Joint7SpinnerValueChanged(app, event)
    

                 % IGNORE THIS
% 
% if(app.count<=2)
% 
%             Joint_1_1  = event.Value;
%             Joint_1_2  = event.PreviousValue;
%         
%             Joint_1_1 =deg2rad(Joint_1_1);
%             Joint_1_2 =deg2rad(Joint_1_2);
%             q1 = [Joint_1_1 0 0 0 0 0 0];
%             q2 = [Joint_1_2 0 0 0 0 0 0];
%           
%          
%             Qmatrix = jtraj(q2,q1,10);
%             animate(app.Library_robot.model,Qmatrix);
%             drawnow();
%             app.count =0;
%          end 
% 
%         if(app.count>2)
%             app.count=0;
%         end

        end

        % Value changing function: Joint7Spinner
        function Joint7SpinnerValueChanging(app, event)
         app.previous_position  = app.Library_robot.model.getpos;
             
%              if(app.count_1_changing>1)
%                event.Value = app.previous_position(1,7)
            app.Joint_7= event.Value;
%           app.count= app.count+1 
            app.Joint_7 = deg2rad(app.Joint_7);
%              app.qn = [app.Joint_1 app.Joint_2 app.Joint_3 app.Joint_4 app.Joint_5 app.Joint_6 app.Joint_7];
             app.qn = app.previous_position;
             app.qn (1,7) = app.Joint_7;
             
             animate(app.Library_robot.model,app.qn);
             drawnow();

             app.previous_position  = app.Library_robot.model.getpos;
             current_position = app.Library_robot.model.fkine(app.previous_position);
             app.x= current_position(1,4);
             app.y= current_position(2,4);
             app.z= current_position(3,4);

            app.XpositionSpinner.Value = app.x;
            app.YpositionSpinner.Value = app.y;
            app.ZpositionSpinner.Value = app.z;
             
        end

        % Button pushed function: LoadthetestingxyzrobotpositionButton
        function LoadthetestingxyzrobotpositionButtonPushed(app, event)
            current_position  = app.Library_robot.model.getpos;
            qi = [0 10.8 0 -20 0 -27.3 0];
            qi = deg2rad(qi);

            QMatrix = jtraj(current_position,qi,20);
            animate(app.Library_robot.model,QMatrix);
            drawnow();
            % UPDATING THE CURRENT JOINT ONTO THE SPINNER
            app.previous_position =app.Library_robot.model.getpos;
            app.previous_position= rad2deg(app.previous_position);
            app.Joint1Spinner.Value = app.previous_position(1,1);
            app.Joint2Spinner.Value = app.previous_position(1,2);
            app.Joint3Spinner.Value = app.previous_position(1,3);
            app.Joint4Spinner.Value = app.previous_position(1,4);
            app.Joint5Spinner.Value = app.previous_position(1,5);
            app.Joint6Spinner.Value = app.previous_position(1,6);
            app.Joint7Spinner.Value = app.previous_position(1,7);
%             app.count_1_changed  =0;
        end

        % Value changing function: XpositionSpinner
        function XpositionSpinnerValueChanging(app, event)
                 app.x = event.Value;
            q0 = app.Library_robot.model.getpos;
            speed = 0.05; % In m/s, change as necessary
            stepsPerSecond = 20; % Arbitrary - play around with this and see how it changes
            epsilon = 0.3;      % Also arbitrary
            W = diag([1 1 1 0.1 0.1 0.1]);      % Threshold value for manipulability/Damped Least Squares

            A = app.Library_robot.model.fkine(q0);
            B = transl(app.x,app.y,app.z);
            changeInPose = B - A;
            distanceTotal = (changeInPose(1,4)^2 + changeInPose(2,4)^2 + changeInPose(3,4)^2)^0.5;
            timeTotal = distanceTotal / speed;
            stepsTotal = stepsPerSecond*timeTotal;  % Arbitrarily set 20 steps per second
            deltaT = timeTotal/stepsTotal;  

            manipulability = zeros(fix(stepsTotal),1);   % Measure of manipulability at each step     fix() cuts off the decimals, making the number an integer
            qMatrix = zeros(fix(stepsTotal),7);          % qMatrix for arm motion
            qDotMatrix = zeros(fix(stepsTotal),7);       % derivitive of above matrix
            xyzMatrix = zeros(3,fix(stepsTotal));        % x-y-z motion Matrix
            positionError = zeros(3,fix(stepsTotal));  

            trapezoidalScalar = lspb(0,1,stepsTotal);
            for i=1:stepsTotal
                xyzMatrix(1,i) = (1-trapezoidalScalar(i))*A(1,4) + trapezoidalScalar(i)*B(1,4);
                xyzMatrix(2,i) = (1-trapezoidalScalar(i))*A(2,4) + trapezoidalScalar(i)*B(2,4);
                xyzMatrix(3,i) = (1-trapezoidalScalar(i))*A(3,4) + trapezoidalScalar(i)*B(3,4);
            end

            T = A;                                          % Initial EF transform
            qMatrix(1,:) = q0;                        % Set initial q values as row 1 of qMatrix
            for i=1:stepsTotal-1
                T = app.Library_robot.model.fkine(qMatrix(i,:));         % Get current EF transform
                deltaX = xyzMatrix(:,i+1) - T(1:3,4);       % Difference between NEXT desired cartesian point, and our current EF transform (3x1 array)
                J = app.Library_robot.model.jacob0(qMatrix(i,:));        % Get current Jacobian
                manipulability(i) = sqrt(det(J*J'));        % Get maanipulability
                linearVelocity = deltaX/deltaT;             % Get linear velocity to next point
                angularVelocity = 0*linearVelocity;         % Placeholder for the angular velocity (we do not perform RMRC on angle
                xDot = W*[linearVelocity;angularVelocity];            % We do not worry about the angle change
                if manipulability(i) < epsilon  % If manipulability is less than given threshold
                    lambda = (1 - manipulability(i)/epsilon)*0.001;
                else
                    lambda = 0;
                end
                invJ = inv(J'*J + lambda*eye(7))*J';        % DLS inverse
                qDotMatrix(i,:) = invJ * xDot;
                % We do not check if joint limits are maintained
                qMatrix(i+1,:) = qMatrix(i,:) + deltaT*qDotMatrix(i,:);
                positionError(:,i) = xyzMatrix(:,i+1) - T(1:3,4);
            end
            animate(app.Library_robot.model,qMatrix);
            drawnow();
            app.previous_position =app.Library_robot.model.getpos;
            app.previous_position= rad2deg(app.previous_position);
            app.Joint1Spinner.Value = app.previous_position(1,1);
            app.Joint2Spinner.Value = app.previous_position(1,2);
            app.Joint3Spinner.Value = app.previous_position(1,3);
            app.Joint4Spinner.Value = app.previous_position(1,4);
            app.Joint5Spinner.Value = app.previous_position(1,5);
            app.Joint6Spinner.Value = app.previous_position(1,6);
            app.Joint7Spinner.Value = app.previous_position(1,7);
            
        end

        % Value changing function: YpositionSpinner
        function YpositionSpinnerValueChanging(app, event)
            app.y = event.Value;
            q0 = app.Library_robot.model.getpos;
            speed = 0.05; % In m/s, change as necessary
            stepsPerSecond = 20; % Arbitrary - play around with this and see how it changes
            epsilon = 0.3;      % Also arbitrary
            W = diag([1 1 1 0.1 0.1 0.1]);      % Threshold value for manipulability/Damped Least Squares

            A = app.Library_robot.model.fkine(q0);
            
            B = transl(app.x,app.y,app.z);
            changeInPose = B - A;
            distanceTotal = (changeInPose(1,4)^2 + changeInPose(2,4)^2 + changeInPose(3,4)^2)^0.5;
            timeTotal = distanceTotal / speed;
            stepsTotal = stepsPerSecond*timeTotal;  % Arbitrarily set 20 steps per second
            deltaT = timeTotal/stepsTotal;  

            manipulability = zeros(fix(stepsTotal),1);   % Measure of manipulability at each step     fix() cuts off the decimals, making the number an integer
            qMatrix = zeros(fix(stepsTotal),7);          % qMatrix for arm motion
            qDotMatrix = zeros(fix(stepsTotal),7);       % derivitive of above matrix
            xyzMatrix = zeros(3,fix(stepsTotal));        % x-y-z motion Matrix
            positionError = zeros(3,fix(stepsTotal));  

            trapezoidalScalar = lspb(0,1,stepsTotal);
            for i=1:stepsTotal
                xyzMatrix(1,i) = (1-trapezoidalScalar(i))*A(1,4) + trapezoidalScalar(i)*B(1,4);
                xyzMatrix(2,i) = (1-trapezoidalScalar(i))*A(2,4) + trapezoidalScalar(i)*B(2,4);
                xyzMatrix(3,i) = (1-trapezoidalScalar(i))*A(3,4) + trapezoidalScalar(i)*B(3,4);
            end

            T = A;                                          % Initial EF transform
            qMatrix(1,:) = q0;                        % Set initial q values as row 1 of qMatrix
            for i=1:stepsTotal-1
                T = app.Library_robot.model.fkine(qMatrix(i,:));         % Get current EF transform
                deltaX = xyzMatrix(:,i+1) - T(1:3,4);       % Difference between NEXT desired cartesian point, and our current EF transform (3x1 array)
                J = app.Library_robot.model.jacob0(qMatrix(i,:));        % Get current Jacobian
                manipulability(i) = sqrt(det(J*J'));        % Get maanipulability
                linearVelocity = deltaX/deltaT;             % Get linear velocity to next point
                angularVelocity = 0*linearVelocity;         % Placeholder for the angular velocity (we do not perform RMRC on angle
                xDot = W*[linearVelocity;angularVelocity];            % We do not worry about the angle change
                if manipulability(i) < epsilon  % If manipulability is less than given threshold
                    lambda = (1 - manipulability(i)/epsilon)*0.001;
                else
                    lambda = 0;
                end
                invJ = inv(J'*J + lambda*eye(7))*J';        % DLS inverse
                qDotMatrix(i,:) = invJ * xDot;
                % We do not check if joint limits are maintained
                qMatrix(i+1,:) = qMatrix(i,:) + deltaT*qDotMatrix(i,:);
                positionError(:,i) = xyzMatrix(:,i+1) - T(1:3,4);
            end
            animate(app.Library_robot.model,qMatrix);
            drawnow();
            app.previous_position =app.Library_robot.model.getpos;
            app.previous_position= rad2deg(app.previous_position);
            app.Joint1Spinner.Value = app.previous_position(1,1);
            app.Joint2Spinner.Value = app.previous_position(1,2);
            app.Joint3Spinner.Value = app.previous_position(1,3);
            app.Joint4Spinner.Value = app.previous_position(1,4);
            app.Joint5Spinner.Value = app.previous_position(1,5);
            app.Joint6Spinner.Value = app.previous_position(1,6);
            app.Joint7Spinner.Value = app.previous_position(1,7);

            
        end

        % Value changing function: ZpositionSpinner
        function ZpositionSpinnerValueChanging(app, event)
                     app.z = event.Value;
            q0 = app.Library_robot.model.getpos;
            speed = 0.05; % In m/s, change as necessary
            stepsPerSecond = 20; % Arbitrary - play around with this and see how it changes
            epsilon = 0.3;      % Also arbitrary
            W = diag([1 1 1 0.1 0.1 0.1]);      % Threshold value for manipulability/Damped Least Squares

            A = app.Library_robot.model.fkine(q0);
            B = transl(app.x,app.y,app.z);
            changeInPose = B - A;
            distanceTotal = (changeInPose(1,4)^2 + changeInPose(2,4)^2 + changeInPose(3,4)^2)^0.5;
            timeTotal = distanceTotal / speed;
            stepsTotal = stepsPerSecond*timeTotal;  % Arbitrarily set 20 steps per second
            deltaT = timeTotal/stepsTotal;  

            manipulability = zeros(fix(stepsTotal),1);   % Measure of manipulability at each step     fix() cuts off the decimals, making the number an integer
            qMatrix = zeros(fix(stepsTotal),7);          % qMatrix for arm motion
            qDotMatrix = zeros(fix(stepsTotal),7);       % derivitive of above matrix
            xyzMatrix = zeros(3,fix(stepsTotal));        % x-y-z motion Matrix
            positionError = zeros(3,fix(stepsTotal));  

            trapezoidalScalar = lspb(0,1,stepsTotal);
            for i=1:stepsTotal
                xyzMatrix(1,i) = (1-trapezoidalScalar(i))*A(1,4) + trapezoidalScalar(i)*B(1,4);
                xyzMatrix(2,i) = (1-trapezoidalScalar(i))*A(2,4) + trapezoidalScalar(i)*B(2,4);
                xyzMatrix(3,i) = (1-trapezoidalScalar(i))*A(3,4) + trapezoidalScalar(i)*B(3,4);
            end

            T = A;                                          % Initial EF transform
            qMatrix(1,:) = q0;                        % Set initial q values as row 1 of qMatrix
            for i=1:stepsTotal-1
                T = app.Library_robot.model.fkine(qMatrix(i,:));         % Get current EF transform
                deltaX = xyzMatrix(:,i+1) - T(1:3,4);       % Difference between NEXT desired cartesian point, and our current EF transform (3x1 array)
                J = app.Library_robot.model.jacob0(qMatrix(i,:));        % Get current Jacobian
                manipulability(i) = sqrt(det(J*J'));        % Get maanipulability
                linearVelocity = deltaX/deltaT;             % Get linear velocity to next point
                angularVelocity = 0*linearVelocity;         % Placeholder for the angular velocity (we do not perform RMRC on angle
                xDot = W*[linearVelocity;angularVelocity];            % We do not worry about the angle change
                if manipulability(i) < epsilon  % If manipulability is less than given threshold
                    lambda = (1 - manipulability(i)/epsilon)*0.001;
                else
                    lambda = 0;
                end
                invJ = inv(J'*J + lambda*eye(7))*J';        % DLS inverse
                qDotMatrix(i,:) = invJ * xDot;
                % We do not check if joint limits are maintained
                qMatrix(i+1,:) = qMatrix(i,:) + deltaT*qDotMatrix(i,:);
                positionError(:,i) = xyzMatrix(:,i+1) - T(1:3,4);
            end
            animate(app.Library_robot.model,qMatrix);
            drawnow();

            app.previous_position =app.Library_robot.model.getpos;
            app.previous_position= rad2deg(app.previous_position);
            app.Joint1Spinner.Value = app.previous_position(1,1);
            app.Joint2Spinner.Value = app.previous_position(1,2);
            app.Joint3Spinner.Value = app.previous_position(1,3);
            app.Joint4Spinner.Value = app.previous_position(1,4);
            app.Joint5Spinner.Value = app.previous_position(1,5);
            app.Joint6Spinner.Value = app.previous_position(1,6);
            app.Joint7Spinner.Value = app.previous_position(1,7);
            
        end

        % Callback function
        function TestingxSpinnerValueChanging(app, event)
%            app.y = event.Value;
%             q0 = app.Library_robot.model.getpos;
%             qi =app.Library_robot.model.ikine(transl(app.x,app.y,app.z));
%             animate(app.Library_robot.model,qi)
            
        end

        % Value changed function: EstopSwitch
        function EstopSwitchValueChanged(app, event)
            value = app.EstopSwitch.Value;
            if strgcmp(value,'On')
                app.Library_robot.Estop();
            else 
                app.Library_access.StartRobot();
            end 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadtherobotButton = uibutton(app.UIFigure, 'push');
            app.loadtherobotButton.ButtonPushedFcn = createCallbackFcn(app, @loadtherobotButtonPushed, true);
            app.loadtherobotButton.Text = 'load the robot';
            app.Joint2SpinnerLabel = uilabel(app.UIFigure);
            app.Joint2SpinnerLabel.Text = 'Joint 2';
            app.Joint2Spinner = uispinner(app.UIFigure);
            app.Joint2Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint2SpinnerValueChanging, true);
            app.Joint2Spinner.Limits = [-135 135];
            app.Joint2Spinner.ValueChangedFcn = createCallbackFcn(app, @Joint2SpinnerValueChanged, true);
            app.Joint3SpinnerLabel = uilabel(app.UIFigure);
            app.Joint3SpinnerLabel.Text = 'Joint 3';
            app.Joint3Spinner = uispinner(app.UIFigure);
            app.Joint3Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint3SpinnerValueChanging, true);
            app.Joint3Spinner.Limits = [-150 150];
            app.Joint3Spinner.ValueChangedFcn = createCallbackFcn(app, @Joint3SpinnerValueChanged, true);
            app.Joint5SpinnerLabel = uilabel(app.UIFigure);
            app.Joint5SpinnerLabel.Text = 'Joint 5';
            app.Joint5Spinner = uispinner(app.UIFigure);
            app.Joint5Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint5SpinnerValueChanging, true);
            app.Joint5Spinner.Limits = [-105 105];
            app.Joint5Spinner.ValueChangedFcn = createCallbackFcn(app, @Joint5SpinnerValueChanged, true);
            app.Joint4SpinnerLabel = uilabel(app.UIFigure);
            app.Joint4SpinnerLabel.Text = 'Joint 4';
            app.Joint4Spinner = uispinner(app.UIFigure);
            app.Joint4Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint4SpinnerValueChanging, true);
            app.Joint4Spinner.Limits = [-150 150];
            app.Joint4Spinner.ValueChangedFcn = createCallbackFcn(app, @Joint4SpinnerValueChanged, true);
            app.Joint6SpinnerLabel = uilabel(app.UIFigure);
            app.Joint6SpinnerLabel.Text = 'Joint 6';
            app.Joint6Spinner = uispinner(app.UIFigure);
            app.Joint6Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint6SpinnerValueChanging, true);
            app.Joint6Spinner.Limits = [-105 105];
            app.Joint6Spinner.ValueChangedFcn = createCallbackFcn(app, @Joint6SpinnerValueChanged, true);
            app.Joint7SpinnerLabel = uilabel(app.UIFigure);
            app.Joint7SpinnerLabel.Text = 'Joint 7';
            app.Joint7Spinner = uispinner(app.UIFigure);
            app.Joint7Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint7SpinnerValueChanging, true);
            app.Joint7Spinner.Limits = [-150 150];
            app.Joint7Spinner.ValueChangedFcn = createCallbackFcn(app, @Joint7SpinnerValueChanged, true);
            app.YpositionSpinnerLabel = uilabel(app.UIFigure);
            app.YpositionSpinnerLabel.Text = 'Y position';
            app.YpositionSpinner = uispinner(app.UIFigure);
            app.YpositionSpinner.Step = 0.01;
            app.YpositionSpinner.ValueChangingFcn = createCallbackFcn(app, @YpositionSpinnerValueChanging, true);
            app.YpositionSpinner.Value = -0.21;
            app.Joint1SpinnerLabel = uilabel(app.UIFigure);
            app.Joint1SpinnerLabel.Text = 'Joint 1';
            app.Joint1Spinner = uispinner(app.UIFigure);
            app.Joint1Spinner.ValueChangingFcn = createCallbackFcn(app, @Joint1SpinnerValueChanging, true);
            app.Joint1Spinner.Limits = [-360 360];
            app.Joint1Spinner.ValueChangedFcn = createCallbackFcn(app, @Joint1SpinnerValueChanged, true);
            app.Joint1Spinner.Editable = 'off';
            app.LoadthetestingxyzrobotpositionButton = uibutton(app.UIFigure, 'push');
            app.LoadthetestingxyzrobotpositionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadthetestingxyzrobotpositionButtonPushed, true);
            app.LoadthetestingxyzrobotpositionButton.Text = 'Load the testing xyz robot position';
            app.XpositionSpinnerLabel = uilabel(app.UIFigure);
            app.XpositionSpinnerLabel.Text = 'X position';
            app.XpositionSpinner = uispinner(app.UIFigure);
            app.XpositionSpinner.Step = 0.01;
            app.XpositionSpinner.ValueChangingFcn = createCallbackFcn(app, @XpositionSpinnerValueChanging, true);
            app.ZpositionSpinnerLabel = uilabel(app.UIFigure);
            app.ZpositionSpinnerLabel.Text = 'Z position';
            app.ZpositionSpinner = uispinner(app.UIFigure);
            app.ZpositionSpinner.Step = 0.01;
            app.ZpositionSpinner.ValueChangingFcn = createCallbackFcn(app, @ZpositionSpinnerValueChanging, true);
            app.ZpositionSpinner.Value = 0.45;
            app.Q1EditField_3Label = uilabel(app.UIFigure);
            app.Q1EditField_3Label.Text = 'Q1';
            app.Q1EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField_6Label = uilabel(app.UIFigure);
            app.Q1EditField_6Label.Text = 'Q1';
            app.Q1EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EstopSwitchLabel = uilabel(app.UIFigure);
            app.EstopSwitchLabel.Text = 'E stop';
            app.EstopSwitch = uiswitch(app.UIFigure, 'slider');
            app.EstopSwitch.ValueChangedFcn = createCallbackFcn(app, @EstopSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end