classdef NewInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ESTOPButton           matlab.ui.control.StateButton
        StartRobotButton      matlab.ui.control.Button
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        JOINTCONTROLLabel     matlab.ui.control.Label
        Q1Label               matlab.ui.control.Label
        Button_3              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Q2Label               matlab.ui.control.Label
        Button_5              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Q3Label               matlab.ui.control.Label
        Button_7              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Q4Label               matlab.ui.control.Label
        Button_9              matlab.ui.control.Button
        Button_10             matlab.ui.control.Button
        Q5Label               matlab.ui.control.Label
        Button_11             matlab.ui.control.Button
        Button_12             matlab.ui.control.Button
        Q6Label               matlab.ui.control.Label
        Button_13             matlab.ui.control.Button
        Button_14             matlab.ui.control.Button
        Q7Label               matlab.ui.control.Label
        RMRCCONTROLLabel      matlab.ui.control.Label
        Button_15             matlab.ui.control.Button
        Button_16             matlab.ui.control.Button
        XLabel                matlab.ui.control.Label
        Button_17             matlab.ui.control.Button
        Button_18             matlab.ui.control.Button
        YLabel                matlab.ui.control.Label
        Button_19             matlab.ui.control.Button
        Button_20             matlab.ui.control.Button
        ZLabel                matlab.ui.control.Label
        WithdrawBook1Button   matlab.ui.control.Button
        DepositBook1Button_4  matlab.ui.control.Button
        DepositBook2Button    matlab.ui.control.Button
        DepositBook3Button    matlab.ui.control.Button
        WithdrawBook2Button   matlab.ui.control.Button
        WithdrawBook3Button   matlab.ui.control.Button
        ReturnToOriginButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        myInterface = InterfaceClass(); % Description
        qSelected = 1; % Current q variable selected (for moving)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            app.myInterface.EStop();
        end

        % Button pushed function: StartRobotButton
        function StartRobotButtonPushed(app, event)
            app.myInterface.HansCute.StartRobot();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.myInterface.DirectQControl(1,1);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.myInterface.DirectQControl(1,-1);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.myInterface.DirectQControl(2,1);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.myInterface.DirectQControl(2,-1);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.myInterface.DirectQControl(3,1);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.myInterface.DirectQControl(3,-1);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.myInterface.DirectQControl(4,1);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.myInterface.DirectQControl(4,-1);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.myInterface.DirectQControl(5,1);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.myInterface.DirectQControl(5,-1);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.myInterface.DirectQControl(6,1);
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.myInterface.DirectQControl(6,-1);
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.myInterface.DirectQControl(7,1);
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.myInterface.DirectQControl(7,-1);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.myInterface.RMRC([1 0 0]);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.myInterface.RMRC([-1 0 0]);
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.myInterface.RMRC([0 1 0]);
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.myInterface.RMRC([0 -1 0]);
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.myInterface.RMRC([0 0 1]);
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.myInterface.RMRC([0 0 -1]);
        end

        % Button pushed function: DepositBook1Button_4
        function DepositBook1Button_4Pushed(app, event)
            app.myInterface.ReturnBook(1);
        end

        % Button pushed function: DepositBook2Button
        function DepositBook2ButtonPushed(app, event)
            app.myInterface.ReturnBook(2);
        end

        % Button pushed function: DepositBook3Button
        function DepositBook3ButtonPushed(app, event)
            app.myInterface.ReturnBook(3);
        end

        % Button pushed function: WithdrawBook1Button
        function WithdrawBook1ButtonPushed(app, event)
            app.myInterface.GetBook(1);
        end

        % Button pushed function: WithdrawBook2Button
        function WithdrawBook2ButtonPushed(app, event)
            app.myInterface.GetBook(2);
        end

        % Button pushed function: WithdrawBook3Button
        function WithdrawBook3ButtonPushed(app, event)
            app.myInterface.GetBook(3);
        end

        % Button pushed function: ReturnToOriginButton
        function ReturnToOriginButtonPushed(app, event)
            app.myInterface.GoToOrigin();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.StartRobotButton = uibutton(app.UIFigure, 'push');
            app.StartRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StartRobotButtonPushed, true);
            app.StartRobotButton.Text = 'Start Robot';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.JOINTCONTROLLabel = uilabel(app.UIFigure);
            app.JOINTCONTROLLabel.Text = 'JOINT CONTROL';
            app.Q1Label = uilabel(app.UIFigure);
            app.Q1Label.Text = 'Q1';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.Q2Label = uilabel(app.UIFigure);
            app.Q2Label.Text = 'Q2';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '-';
            app.Q3Label = uilabel(app.UIFigure);
            app.Q3Label.Text = 'Q3';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '+';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '-';
            app.Q4Label = uilabel(app.UIFigure);
            app.Q4Label.Text = 'Q4';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '+';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '-';
            app.Q5Label = uilabel(app.UIFigure);
            app.Q5Label.Text = 'Q5';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '+';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '-';
            app.Q6Label = uilabel(app.UIFigure);
            app.Q6Label.Text = 'Q6';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '+';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '-';
            app.Q7Label = uilabel(app.UIFigure);
            app.Q7Label.Text = 'Q7';
            app.RMRCCONTROLLabel = uilabel(app.UIFigure);
            app.RMRCCONTROLLabel.Text = 'RMRC CONTROL';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '+';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '-';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '+';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '-';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '+';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '-';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z';
            app.WithdrawBook1Button = uibutton(app.UIFigure, 'push');
            app.WithdrawBook1Button.ButtonPushedFcn = createCallbackFcn(app, @WithdrawBook1ButtonPushed, true);
            app.WithdrawBook1Button.Text = 'Withdraw Book 1';
            app.DepositBook1Button_4 = uibutton(app.UIFigure, 'push');
            app.DepositBook1Button_4.ButtonPushedFcn = createCallbackFcn(app, @DepositBook1Button_4Pushed, true);
            app.DepositBook1Button_4.Text = 'Deposit Book 1';
            app.DepositBook2Button = uibutton(app.UIFigure, 'push');
            app.DepositBook2Button.ButtonPushedFcn = createCallbackFcn(app, @DepositBook2ButtonPushed, true);
            app.DepositBook2Button.Text = 'Deposit Book 2';
            app.DepositBook3Button = uibutton(app.UIFigure, 'push');
            app.DepositBook3Button.ButtonPushedFcn = createCallbackFcn(app, @DepositBook3ButtonPushed, true);
            app.DepositBook3Button.Text = 'Deposit Book 3';
            app.WithdrawBook2Button = uibutton(app.UIFigure, 'push');
            app.WithdrawBook2Button.ButtonPushedFcn = createCallbackFcn(app, @WithdrawBook2ButtonPushed, true);
            app.WithdrawBook2Button.Text = 'Withdraw Book 2';
            app.WithdrawBook3Button = uibutton(app.UIFigure, 'push');
            app.WithdrawBook3Button.ButtonPushedFcn = createCallbackFcn(app, @WithdrawBook3ButtonPushed, true);
            app.WithdrawBook3Button.Text = 'Withdraw Book 3';
            app.ReturnToOriginButton = uibutton(app.UIFigure, 'push');
            app.ReturnToOriginButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnToOriginButtonPushed, true);
            app.ReturnToOriginButton.Text = 'Return To Origin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end